classdef Perovskite_Solar_Cell_Performance_Prediction_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        ClearButton                     matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        PredictButton                   matlab.ui.control.Button
        ParameterPanel                  matlab.ui.container.Panel
        ParameterGrid                   matlab.ui.container.GridLayout
        RecombinationVelocityatIIImsup4supssup1supEditField  matlab.ui.control.NumericEditField
        RecombinationVelocityatIIImsup4supssup1supEditFieldLabel  matlab.ui.control.Label
        RecombinationVelocityatIImsup4supssup1supEditField  matlab.ui.control.NumericEditField
        RecombinationVelocityatIImsup4supssup1supEditFieldLabel  matlab.ui.control.Label
        HoleLifetimeinPsEditField       matlab.ui.control.NumericEditField
        HoleLifetimeinPsEditFieldLabel  matlab.ui.control.Label
        ElectronLifetimeinPsEditField   matlab.ui.control.NumericEditField
        ElectronLifetimeinPsEditFieldLabel  matlab.ui.control.Label
        RadiativeRecombinationinPmsup3supssup1supEditField  matlab.ui.control.NumericEditField
        RadiativeRecombinationinPmsup3supssup1supEditFieldLabel  matlab.ui.control.Label
        AugerRecombinationinPmsup6supssup1supEditField  matlab.ui.control.NumericEditField
        AugerRecombinationinPmsup6supssup1supEditFieldLabel  matlab.ui.control.Label
        ChargeGenerationRateinPmsup3supssup1supEditField  matlab.ui.control.NumericEditField
        ChargeGenerationRateinPLabel    matlab.ui.control.Label
        RelativePermittivityinEEditField  matlab.ui.control.NumericEditField
        RelativePermittivityinEEditFieldLabel  matlab.ui.control.Label
        RelativePermittivityinPEditField  matlab.ui.control.NumericEditField
        RelativePermittivityinPEditFieldLabel  matlab.ui.control.Label
        RelativePermittivityinHEditField  matlab.ui.control.NumericEditField
        RelativePermittivityinHEditFieldLabel  matlab.ui.control.Label
        WorkFunctionofFeVEditField      matlab.ui.control.NumericEditField
        WorkFunctionofFeVEditFieldLabel  matlab.ui.control.Label
        WorkFunctionofBeVEditField      matlab.ui.control.NumericEditField
        WorkFunctionofBLabel            matlab.ui.control.Label
        ElectronAffinityinEeVEditField  matlab.ui.control.NumericEditField
        ElectronAffinityinEeVEditFieldLabel  matlab.ui.control.Label
        ElectronAffinityinPeVEditField  matlab.ui.control.NumericEditField
        ElectronAffinityinPeVEditFieldLabel  matlab.ui.control.Label
        ElectronAffinityinHeVEditField  matlab.ui.control.NumericEditField
        ElectronAffinityinHeVEditFieldLabel  matlab.ui.control.Label
        HoleIonizationPotentialinEeVEditField  matlab.ui.control.NumericEditField
        HoleIonizationPotentialinEeVEditFieldLabel  matlab.ui.control.Label
        HoleIonizationPotentialinPeVEditField  matlab.ui.control.NumericEditField
        HoleIonizationPotentialinPeVEditFieldLabel  matlab.ui.control.Label
        HoleIonizationPotentialinHeVEditField  matlab.ui.control.NumericEditField
        HoleIonizationPotentialinHeVEditFieldLabel  matlab.ui.control.Label
        ValanceBandDensityinEmsup3supEditField  matlab.ui.control.NumericEditField
        ValanceBandDensityinEmsup3supEditFieldLabel  matlab.ui.control.Label
        ConductionBandDensityinEmsup3supEditField  matlab.ui.control.NumericEditField
        ConductionBandDensityinEmsup3supEditFieldLabel  matlab.ui.control.Label
        ConductionBandDensityinPmsup3supEditField  matlab.ui.control.NumericEditField
        ConductionBandDensityinPmsup3supEditFieldLabel  matlab.ui.control.Label
        ValanceBandDensityinPmsup3supEditField  matlab.ui.control.NumericEditField
        ValanceBandDensityinPLabel      matlab.ui.control.Label
        ConductionBandDensityinHmsup3supEditField  matlab.ui.control.NumericEditField
        ConductionBandDensityinHmsup3supEditFieldLabel  matlab.ui.control.Label
        ValanceBandDensityinHmsup3supEditField  matlab.ui.control.NumericEditField
        ValanceBandDensityinHmsup3supEditFieldLabel  matlab.ui.control.Label
        ElectronMobilityinEmsup2supVsup1supssup1supEditField  matlab.ui.control.NumericEditField
        ElectronMobilityinELabel        matlab.ui.control.Label
        ElectronMobilityinPmsup2supVsup1supssup1supEditField  matlab.ui.control.NumericEditField
        ElectronMobilityinPmsup2supVsup1supssup1supEditFieldLabel  matlab.ui.control.Label
        HoleMobilityinPmsup2supVsup1supssup1supEditField  matlab.ui.control.NumericEditField
        HoleMobilityinPmsup2supVsup1supssup1supEditFieldLabel  matlab.ui.control.Label
        HoleMobilityinHmsup2supVsup1supssup1supEditField  matlab.ui.control.NumericEditField
        HoleMobilityinHLabel            matlab.ui.control.Label
        ELayerThicknessnmEditField      matlab.ui.control.NumericEditField
        ELayerThicknessnmEditFieldLabel  matlab.ui.control.Label
        PLayerThicknessnmEditField      matlab.ui.control.NumericEditField
        PLayerThicknessnmEditFieldLabel  matlab.ui.control.Label
        HLayerThicknessnmEditField      matlab.ui.control.NumericEditField
        HLayerThicknessnmEditFieldLabel  matlab.ui.control.Label
        SolarCellParametersLabel        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        EditDropDown                    matlab.ui.control.DropDown
        EditDropDownLabel               matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        ListBox                         matlab.ui.control.ListBox
        HistoryLabel                    matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        PerformanceGrid                 matlab.ui.container.GridLayout
        CurveGrid                       matlab.ui.container.GridLayout
        EfficiencyEditField             matlab.ui.control.NumericEditField
        EfficiencyEditField_2Label      matlab.ui.control.Label
        FillFactorEditField             matlab.ui.control.NumericEditField
        FillFactorEditField_2Label      matlab.ui.control.Label
        MaxPowerPointVoltageVEditField  matlab.ui.control.NumericEditField
        MaxPowerPointVoltageVEditFieldLabel  matlab.ui.control.Label
        MaxPowerPointCurrentAEditField  matlab.ui.control.NumericEditField
        MaxPowerPointCurrentAEditFieldLabel  matlab.ui.control.Label
        OpenCircuitVoltageVEditField    matlab.ui.control.NumericEditField
        OpenCircuitVoltageVEditFieldLabel  matlab.ui.control.Label
        ShortCircuitCurrentAEditField   matlab.ui.control.NumericEditField
        ShortCircuitCurrentAEditFieldLabel  matlab.ui.control.Label
        Curve                           matlab.ui.control.UIAxes
        CellLayout                      matlab.ui.control.UIAxes
        PerformanceLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        Isc_Model
        Voc_Model
        Vmpp_Model
        Impp_Model
        Int_Model
        Calibrate_Model
        NormTable

        TableNames
        
        Hlayer
        Player
        Elayer
    end
    
    methods (Access = private)
        
        function setRowState(app, rows, state)

            if state
                app.ParameterGrid.RowHeight(rows) = {'fit'};
            else
                app.ParameterGrid.RowHeight(rows) = {0};
            end

            comps = app.ParameterGrid.Children;
            for k = 1:numel(comps)
                if ismember(comps(k).Layout.Row, rows)
                    if state
                        comps(k).Visible = "on";
                        comps(k).Enable  = "on";
                    else
                        comps(k).Visible = "off";
                        comps(k).Enable  = "off";
                    end
                end
            end
        end
        
        function data = readNumericFields(app, parent)
            fields = findall(parent, 'Type', 'uinumericeditfield');
        
            data = struct();
            for k = 1:numel(fields)
                tag = string(fields(k).Tag);
        
                if strlength(tag) == 0
                    continue; % skip fields without Tag
                end
        
                key = matlab.lang.makeValidName(tag);  % safe field name
                data.(key) = fields(k).Value;
            end

            disp(numel(fields))
        end
        
        function [Int_table, V1_to_V12_Predictions] = GetIntermediatePoints(app,InputData_table, Voc_Predictions, Vmpp_Predictions)
            % Number of intermediate points
            n_pre  = 4;   % between Isc (0) and Vmpp
            n_post = 8;   % between Vmpp and Voc
            
            % Extract Voc and Vmpp as vectors
            Voc  = Voc_Predictions;
            Vmpp = Vmpp_Predictions;
            
            N = height(Voc_Predictions);
            
            % Preallocate
            intV = zeros(N, n_pre + n_post);
            
            % ---- 4 points between Isc (0) and Vmpp ----
            for k = 1:n_pre
                alpha = k / (n_pre + 1);
                intV(:,k) = alpha .* Vmpp;
            end
            
            % ---- 8 points between Vmpp and Voc ----
            for k = 1:n_post
                alpha = k / (n_post + 1);
                intV(:,n_pre + k) = Vmpp + alpha .* (Voc - Vmpp);
            end
            
            % ---- Convert to table with proper names ----
            varNames = "int_v_" + string(1:(n_pre + n_post));
            V1_to_V12_Predictions = array2table(intV, 'VariableNames', varNames);
            
            % ---- Append to dataset ----
            Int_table = ...
                [InputData_table, V1_to_V12_Predictions];
        end

        
        function [I15_Predicted, V15_Predicted] = ReconstructCurve(app,Int_Predictions, V1_to_V12_Predictions, Isc_Predictions, Impp_Predictions, Vmpp_Predictions, Voc_Predictions)

            I12 = array2table(Int_Predictions); 
            if istable(I12); I12 = table2array(I12); end
            
            I15_Predicted = array2table( ...
                [Isc_Predictions, I12(:,1:4), Impp_Predictions, I12(:,5:12), zeros(size(Isc_Predictions))], ...
                "VariableNames", ["Isc","i"+string(1:4),"Impp","i"+string(5:12),"Iend"] ...
            );
            
            V12 = V1_to_V12_Predictions;
            if istable(V12); V12 = table2array(V12); end
            
            V15_Predicted = array2table( ...
                [zeros(size(Voc_Predictions)), V12(:,1:4), Vmpp_Predictions, V12(:,5:12), Voc_Predictions], ...
                "VariableNames", ["V0","v"+string(1:4),"Vmpp","v"+string(5:12),"Voc"] ...
            );
        end

        function plotPredictedIVCurve(app, I15_Predicted, V15_Predicted)
            % Convert table → array
            I = table2array(I15_Predicted);
            V = table2array(V15_Predicted);
            
            % Force row vectors (safety)
            I = I(:).';
            V = V(:).';
            
            % Sort by voltage
            [V, idx] = sort(V);
            I = I(idx);
            
            % Clear and plot on UIAxes
            cla(app.Curve);
            plot(app.Curve, V, I, '-o', ...
                'LineWidth', 1.8, ...
                'MarkerSize', 6, ...
                'MarkerFaceColor', 'auto');
            % Axes formatting
            grid(app.Curve,'on');
            xlabel(app.Curve,'Voltage (V)');
            ylabel(app.Curve,'Current Density (A m^{-2})');
            title(app.Curve,'Predicted IV Curve');          
        end

        
        function InputData = normaliseInputs(app, InputData_table, NormTable)
            % Expected variable order (must match how NormTable was fitted)
            vars = string(app.NormTable.VarName).';
        
            % Reorder input row to match training order (CRITICAL)
            InputData_table = InputData_table(:, vars);
        
            % 1x31 numeric
            x = table2array(InputData_table);
        
            % Ensure isLog is logical
            rawIsLog = app.NormTable.IsLog;
            if islogical(rawIsLog)
                isLog = rawIsLog(:).';
            elseif isnumeric(rawIsLog)
                isLog = logical(rawIsLog(:).');
            else
                rawIsLog = string(rawIsLog);
                isLog = (strcmpi(rawIsLog,"true") | strcmpi(rawIsLog,"yes") | rawIsLog=="1").';
            end
        
            mu    = app.NormTable.Mu(:).';
            sigma = app.NormTable.Sigma(:).';
            zmin  = app.NormTable.Zmin(:).';
            zmax  = app.NormTable.Zmax(:).';
        
            % Log10 where required
            xlog = x;
            xlog(:, isLog) = log10(x(:, isLog));   % match your original exactly
            
            % Z-score
            z = (xlog - mu) ./ sigma;
            
            % Handle constant columns (sigma=0)
            zeroSigma = (sigma == 0) | ~isfinite(sigma);
            z(:, zeroSigma) = 0;
            
            % Min-max to [-1,1]
            den = (zmax - zmin);
            zmm = 2 * (z - zmin) ./ den - 1;
            
            % Handle constant columns (den=0)
            zeroDen = (den == 0) | ~isfinite(den);
            zmm(:, zeroDen) = 0;
            
            InputData  = zmm;
        end
        
        function Trow = loadInputDataAsTable(app, filePath)
        % Returns a 1x31 table whose variable names match app.NormTable.VarName
        % Accepts:
        %  - .mat containing InputData (array or table) OR a single variable
        %  - .csv/.xlsx with 1 row (table)
        
            [~,~,ext] = fileparts(filePath);
            ext = lower(string(ext));
        
            expectedVars = string(app.NormTable.VarName).';   % 1x31 expected variable order
            P = numel(expectedVars);
        
            switch ext
                case ".mat"
                    S = load(filePath);
        
                    % Prefer variable named InputData if present
                    if isfield(S,"InputData")
                        obj = S.InputData;
                    else
                        % Otherwise, if there is exactly one variable in the MAT file, use it
                        fn = fieldnames(S);
                        if numel(fn) == 1
                            obj = S.(fn{1});
                        else
                            error("MAT file must contain a variable named 'InputData' or only one variable.");
                        end
                    end
        
                    % Convert obj -> Trow
                    if istable(obj)
                        if height(obj) ~= 1
                            error("InputData table must have exactly 1 row (got %d rows).", height(obj));
                        end
                        Trow = obj;
        
                    elseif isnumeric(obj)
                        if size(obj,1) ~= 1
                            error("InputData array must be 1x31 (got %dx%d).", size(obj,1), size(obj,2));
                        end
                        if size(obj,2) ~= P
                            error("InputData array must have %d columns (got %d).", P, size(obj,2));
                        end
                        Trow = array2table(obj, 'VariableNames', expectedVars);
        
                    else
                        error("InputData must be a table or numeric array. Got: %s", class(obj));
                    end
        
                case ".csv"
                    Trow = readtable(filePath);
                    if height(Trow) ~= 1
                        error("CSV must contain exactly 1 row (got %d rows).", height(Trow));
                    end
        
                case {".xlsx",".xls"}
                    Trow = readtable(filePath);
                    if height(Trow) ~= 1
                        error("Excel file must contain exactly 1 row (got %d rows).", height(Trow));
                    end
        
                otherwise
                    error("Unsupported file type: %s", ext);
            end
        
            % ---- Align columns to expectedVars ----
            % If table has the right names, reorder. If not, error.
            have = string(Trow.Properties.VariableNames);
        
            if all(ismember(expectedVars, have))
                Trow = Trow(:, expectedVars);  % reorder to expected
            else
                missing = expectedVars(~ismember(expectedVars, have));
                error("Loaded table is missing these required variables: %s", strjoin(missing, ", "));
            end
        
            % Final safety check
            if width(Trow) ~= P || height(Trow) ~= 1
                error("After alignment, InputData must be 1x%d. Got %dx%d.", P, height(Trow), width(Trow));
            end
        end

        function setNumericFieldsFromTable(app, parent, Trow)
            % parent: app.InputPanel (or app.UIFigure)
            % Trow: 1-row table with variable names
            
            fields = findall(parent, 'Type', 'uinumericeditfield');
        
            for i = 1:numel(fields)
                tag = string(fields(i).Tag);
                if strlength(tag) == 0
                    continue;
                end
        
                key = matlab.lang.makeValidName(tag);
        
                if ismember(key, string(Trow.Properties.VariableNames))
                    val = Trow{1, key};
        
                    % Handle NaN/empty gracefully
                    if isempty(val) || ~isfinite(val)
                        % choose what you want here; I keep NaN
                        fields(i).Value = NaN;
                    else
                        fields(i).Value = val;
                    end
                end
            end
        end
        
        function data = loadInputDataNumeric(app, filepath)
            [~,~,ext] = fileparts(filepath);
        
            switch lower(ext)
                case '.mat'
                    S = load(filepath);
                    fn = fieldnames(S);
                    data = S.(fn{1});   % first variable
        
                case {'.csv','.xlsx','.xls'}
                    data = readmatrix(filepath);
        
                otherwise
                    error("Unsupported file type: %s", ext);
            end
        
            if ~isnumeric(data)
                error("Loaded data must be numeric.");
            end
        
            if isempty(data)
                error("File contains no numeric data.");
            end
        end

        function plotSolarCell3D(app, LH, LP, LE)

            cla(app.CellLayout);
            
            baseE = 0;
            baseP = LE;
            baseH = LE + LP;

            drawBox(app, app.CellLayout, baseE, LE, 'g');
            drawBox(app, app.CellLayout, baseP, LP, 'r');
            drawBox(app, app.CellLayout, baseH, LH, 'b');

        end
        
        function drawBox(app, ax, z0, dz, color)

        %        8 -------- 7
        %       /|         /|
        %      5 -------- 6 |
        %      | |        | |
        %      | 4 ------ | 3
        %      |/         |/
        %      1 -------- 2    For reference only
            
            F = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8]; % tells code how to draw face
            V = [0 0 z0; 1 0 z0; 1 1 z0; 0 1 z0; 0 0 z0+dz; 1 0 z0+dz; 1 1 z0+dz; 0 1 z0+dz;]; % tells code the coordinate of each corner

            patch('Parent', ax, 'Faces', F, 'Vertices', V, 'FaceColor', color, 'FaceAlpha', 0.5);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Load Models and Norm Table
            % Folder where this app is installed
            appDir = fileparts(which(class(app)));
            
            % Build model folder (put .mat files next to app or in /models)
            modelDir = fullfile(appDir,'models');   % <- use this if in models/ folder
            
            % Load models safely
            S = load(fullfile(modelDir,"Isc_Model.mat"),"Isc_Model");
            app.Isc_Model = S.Isc_Model;
            
            S = load(fullfile(modelDir,"Voc_Model.mat"),"Voc_Model");
            app.Voc_Model = S.Voc_Model;
            
            S = load(fullfile(modelDir,"Vmpp_Model.mat"),"Vmpp_Model");
            app.Vmpp_Model = S.Vmpp_Model;
            
            S = load(fullfile(modelDir,"Impp_Model.mat"),"Impp_Model");
            app.Impp_Model = S.Impp_Model;
            
            S = load(fullfile(modelDir,"Int_Model.mat"),"Int_Model");
            app.Int_Model = S.Int_Model;
            
            S = load(fullfile(modelDir,"NormTable.mat"),"NormTable");
            app.NormTable = S.NormTable;
            
            S = load(fullfile(modelDir,"Calibrate_Model.mat"),"Calibrate_Model");
            app.Calibrate_Model = S.Calibrate_Model;

            app.TableNames = ["LH", "LP", "LE", "muHh", "muPh", "muPe", "muEe", "NvH", "NcH", "NvE", "NcE", "NvP", "NcP", "chiHh", "chiHe", "chiPh", "chiPe", "chiEh", "chiEe", "Wlm", "Whm", "epsH", "epsP", "epsE", "Gavg", "Aug", "Brad", "Taue", "Tauh", "vII", "vIII"];

            %% Load layer heights
            app.Hlayer = 0;
            app.Player = 0;
            app.Elayer = 0;
            
            %% Set row states for input parameters panel
            app.setRowState(1:31, false);
            app.setRowState(1:3, true);
            
        end

        % Value changed function: EditDropDown
        function EditDropDownValueChanged(app, event)
            EditDropDownValue = string(app.EditDropDown.Value);
            app.setRowState(1:31, false);
            r = 0;
            switch EditDropDownValue
                case "Geometric Properties"
                    r = 1;
                    app.setRowState(1:3, true);
                    app.ParameterPanel.Title = " Geometric Properties";
            
                case "Hole Mobility"
                    app.setRowState(4:7, true);
                    app.ParameterPanel.Title = " Hole Mobility";
            
                case "Density of States"
                    app.setRowState(8:13, true);
                    app.ParameterPanel.Title = " Density of States";
            
                case "Energy Level Alignment"
                    app.setRowState(14:19, true);
                    app.ParameterPanel.Title = " Energy Level Alignment";
            
                case "Work Function"
                    app.setRowState(20:21, true);
                    app.ParameterPanel.Title = " Work Function";
            
                case "Relative Permittivity"
                    app.setRowState(22:24, true);
                    app.ParameterPanel.Title = " Relative Permittivity";
            
                case "Charge Generation"
                    app.setRowState(25, true);
                    app.ParameterPanel.Title = " Charge Generation";
            
                case "Recombination Coefficient"
                    app.setRowState(26:27, true);
                    app.ParameterPanel.Title = " Recombination Coefficient";
            
                case "Charge Carrier Lifetime"
                    app.setRowState(28:29, true);
                    app.ParameterPanel.Title = " Charge Carrier Lifetime";
            
                case "Recombination Velocity"
                    app.setRowState(30:31, true);
                    app.ParameterPanel.Title = " Recombination Velocity";
            end

            if r == 1
                app.CellLayout.Visible = 'on';
                app.CurveGrid.Visible = 'off';
                app.PerformanceGrid.RowHeight{1} = 650;
                app.PerformanceGrid.RowHeight{2} = 0;
                app.Curve.Visible = 'off';
            else
                app.CellLayout.Visible = 'off';
                app.CurveGrid.Visible = 'on';
                app.PerformanceGrid.RowHeight{1} = 0;
                app.PerformanceGrid.RowHeight{2} = 'fit';
                app.Curve.Visible = 'on';
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            %% Reads Numeric Fields to obtain InputData
            InputData = struct();
            cats = app.ParameterGrid.Children;
            
            for i = 1:numel(cats)
                s = app.readNumericFields(cats(i));   % struct for this category
                fn = fieldnames(s);
            
                for k = 1:numel(fn)
                    InputData.(fn{k}) = s.(fn{k});  % merge fields
                end
            end

            InputData_table_safe = struct2table(InputData);
            InputData_table_safe = InputData_table_safe(:, app.TableNames);
            InputData = table2array(InputData_table_safe);

            %% Normalises InputData according to training data
            InputData_norm = app.normaliseInputs(InputData_table_safe,app.NormTable);
            
            %% Predicts IV Key Parameters
            Isc_Predictions = app.Isc_Model.predict(InputData_norm);
            Voc_Predictions = app.Voc_Model.predict(InputData_norm);
            Impp_Predictions = app.Impp_Model.predict(InputData_norm);
            Vmpp_Predictions = app.Vmpp_Model.predict(InputData_norm);

            InputData_table = array2table(InputData_norm);

            [Int_table, V1_to_V12_Predictions] = app.GetIntermediatePoints(InputData_table, Voc_Predictions, Vmpp_Predictions);

            Int_Predictions = app.Int_Model.predict(table2array(Int_table));

            [I15_Raw, V15_Predicted] = app.ReconstructCurve(Int_Predictions, V1_to_V12_Predictions, Isc_Predictions, Impp_Predictions, Vmpp_Predictions, Voc_Predictions);

            I15_Predicted = app.Calibrate_Model.predict(table2array(I15_Raw));

            I15_Predicted = array2table(I15_Predicted);

            I15_Predicted.Properties.VariableNames = I15_Raw.Properties.VariableNames;

            app.plotPredictedIVCurve(I15_Predicted, V15_Predicted);

            %% Displays Predicted values
            Isc = double(Isc_Predictions(1));
            Voc = double(Voc_Predictions(1));
            Impp = double(Impp_Predictions(1));
            Vmpp = double(Vmpp_Predictions(1));

            app.ShortCircuitCurrentAEditField.Value = Isc;
            app.OpenCircuitVoltageVEditField.Value = Voc;
            app.MaxPowerPointCurrentAEditField.Value = Impp;
            app.MaxPowerPointVoltageVEditField.Value = Vmpp;
            app.FillFactorEditField.Value = (Impp .* Vmpp) ./ (Isc .* Voc);
            app.EfficiencyEditField.Value = ((Impp .* Vmpp) ./ 1000) .* 100;

            %% Creates new timestamp and saves InputData
            newItem  = datestr(datetime("now","Format","dd/MMM/uuuu HH:mm"));
            newValue = InputData;
            
            % --- Items (strings) ---
            currentItems = app.ListBox.Items;
            if isempty(currentItems)
                currentItems = {};
            end
            currentItems = [{newItem}, currentItems];   % ⬅ newest at top
            
            % --- ItemsData (cells) ---
            currentValues = app.ListBox.ItemsData;
            if isempty(currentValues)
                currentValues = {};
            end
            currentValues = [{newValue}, currentValues]; % ⬅ newest at top
            
            % Update listbox
            app.ListBox.Items     = currentItems;
            app.ListBox.ItemsData = currentValues;

        end

        % Value changed function: HLayerThicknessnmEditField
        function HLayerThicknessnmEditFieldValueChanged(app, event)
            app.Hlayer = app.HLayerThicknessnmEditField.Value;
            app.plotSolarCell3D(app.Hlayer, app.Player, app.Elayer);
        end

        % Value changed function: PLayerThicknessnmEditField
        function PLayerThicknessnmEditFieldValueChanged(app, event)
            app.Player = app.PLayerThicknessnmEditField.Value;
            app.plotSolarCell3D(app.Hlayer, app.Player, app.Elayer);
        end

        % Value changed function: ELayerThicknessnmEditField
        function ELayerThicknessnmEditFieldValueChanged(app, event)
            app.Elayer = app.ELayerThicknessnmEditField.Value;
            app.plotSolarCell3D(app.Hlayer, app.Player, app.Elayer);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Pick file
            [fname, fpath] = uigetfile( ...
                {'*.mat;*.csv;*.xlsx;*.xls', 'Data files (*.mat, *.csv, *.xlsx, *.xls)'; ...
                 '*.*', 'All files (*.*)'}, ...
                'Select Input Data file');
        
            if isequal(fname,0), return; end
            fullfilePath = fullfile(fpath, fname);
        
            try
                % -------- 1) LOAD -> "data" (numeric matrix) --------
                [~,~,ext] = fileparts(fullfilePath);
                ext = lower(string(ext));
        
                switch ext
                    case ".mat"
                        S = load(fullfilePath);
                        fn = fieldnames(S);
                        if isempty(fn)
                            error("MAT file is empty.");
                        end
        
                        % Use InputData if present, else first variable
                        if isfield(S,"InputData")
                            obj = S.InputData;
                        else
                            obj = S.(fn{1});
                        end
        
                        % Convert obj -> numeric
                        if istable(obj)
                            data = table2array(obj);
                        elseif isstruct(obj)
                            data = table2array(struct2table(obj));
                        elseif isnumeric(obj)
                            data = obj;
                        else
                            error("MAT variable must be numeric/table/struct. Got: %s", class(obj));
                        end
        
                    case {".csv",".xlsx",".xls"}
                        data = readmatrix(fullfilePath);
        
                    otherwise
                        error("Unsupported file type: %s", ext);
                end
        
                data = double(data);
        
                % -------- 2) FORCE it to be 1×31 --------
                nExpected = numel(app.TableNames);
        
                % Accept 31×1
                if isvector(data) && numel(data) == nExpected
                    row = reshape(data, 1, []);
                else
                    % Accept Nx31 but take first row
                    if size(data,2) ~= nExpected
                        error("Expected %d columns, got %d.", nExpected, size(data,2));
                    end
                    row = data(1, :);   % <-- easiest: take first row
                end
        
                % -------- 3) SET all numeric fields BY POSITION using app.TableNames --------
                % Build a map: name -> value (using your TableNames order)
                names = string(app.TableNames(:)).';
                names = matlab.lang.makeValidName(names);
        
                % Find all numeric edit fields under your parameter panel
                fields = findall(app.ParameterPanel, 'Type', 'uinumericeditfield');
        
                for k = 1:numel(fields)
                    tag = string(fields(k).Tag);
                    if strlength(tag) == 0
                        continue
                    end
        
                    tag = matlab.lang.makeValidName(tag);
                    idx = find(names == tag, 1);
        
                    if ~isempty(idx)
                        fields(k).Value = row(idx);
                    end
                end
        
                % -------- 4) UPDATE 3-layer plot --------
                % Pull directly from row using the indices of LH/LP/LE
                iLH = find(names=="LH",1);
                iLP = find(names=="LP",1);
                iLE = find(names=="LE",1);
        
                if isempty(iLH) || isempty(iLP) || isempty(iLE)
                    error("app.TableNames must include LH, LP, LE.");
                end
        
                app.plotSolarCell3D(row(iLH), row(iLP), row(iLE));
        
            catch ME
                uialert(app.UIFigure, ME.message, 'Load failed');
            end
        end

        % Double-clicked callback: ListBox
        function ListBoxDoubleClicked(app, event)
            ItemIdx = event.InteractionInformation.Item;
            item = app.ListBox.ItemsData{ItemIdx};
            item = array2table(item,"VariableNames",app.TableNames);

            % ---- Push values into numeric fields ----
            app.setNumericFieldsFromTable(app.ParameterPanel, item);

            app.Hlayer = item.LH;
            app.Player = item.LP;
            app.Elayer = item.LE;
            app.plotSolarCell3D(app.Hlayer, app.Player, app.Elayer);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            item = zeros(1,31);
            item = array2table(item,"VariableNames",app.TableNames);
            app.setNumericFieldsFromTable(app.ParameterPanel, item);

            app.Hlayer = item.LH;
            app.Player = item.LP;
            app.Elayer = item.LE;
            app.plotSolarCell3D(app.Hlayer, app.Player, app.Elayer);
            app.ShortCircuitCurrentAEditField.Value = 0;
            app.OpenCircuitVoltageVEditField.Value = 0;
            app.MaxPowerPointCurrentAEditField.Value = 0;
            app.MaxPowerPointVoltageVEditField.Value = 0;
            app.FillFactorEditField.Value = 0;
            app.EfficiencyEditField.Value = 0;
            cla(app.Curve);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PerformanceLabel = uilabel(app.GridLayout3);
            app.PerformanceLabel.Text = 'Performance';
            app.PerformanceGrid = uigridlayout(app.GridLayout3);
            app.CellLayout = uiaxes(app.PerformanceGrid);
            title(app.CellLayout, '3D Solar Cell Layout')
            xlabel(app.CellLayout, 'Length (1 unit)')
            ylabel(app.CellLayout, 'Width (1 unit)')
            zlabel(app.CellLayout, 'Layer Thickness (nm)')
            app.CellLayout.XTick = [0 1];
            app.CellLayout.YTick = [0 1];
            app.CurveGrid = uigridlayout(app.PerformanceGrid);
            app.CurveGrid.Visible = 'off';
            app.Curve = uiaxes(app.CurveGrid);
            title(app.Curve, 'Predicted IV Curve')
            xlabel(app.Curve, 'Voltage / V')
            ylabel(app.Curve, 'Current Density / Am^{2}')
            zlabel(app.Curve, 'Z')
            app.ShortCircuitCurrentAEditFieldLabel = uilabel(app.CurveGrid);
            app.ShortCircuitCurrentAEditFieldLabel.Text = 'Short Circuit Current (A)';
            app.ShortCircuitCurrentAEditField = uieditfield(app.CurveGrid, 'numeric');
            app.ShortCircuitCurrentAEditField.Tag = 'Isc';
            app.OpenCircuitVoltageVEditFieldLabel = uilabel(app.CurveGrid);
            app.OpenCircuitVoltageVEditFieldLabel.Text = 'Open Circuit Voltage (V)';
            app.OpenCircuitVoltageVEditField = uieditfield(app.CurveGrid, 'numeric');
            app.OpenCircuitVoltageVEditField.Tag = 'Voc';
            app.MaxPowerPointCurrentAEditFieldLabel = uilabel(app.CurveGrid);
            app.MaxPowerPointCurrentAEditFieldLabel.Text = 'Max Power Point Current (A)';
            app.MaxPowerPointCurrentAEditField = uieditfield(app.CurveGrid, 'numeric');
            app.MaxPowerPointCurrentAEditField.Tag = 'Impp';
            app.MaxPowerPointVoltageVEditFieldLabel = uilabel(app.CurveGrid);
            app.MaxPowerPointVoltageVEditFieldLabel.Text = 'Max Power Point Voltage (V)';
            app.MaxPowerPointVoltageVEditField = uieditfield(app.CurveGrid, 'numeric');
            app.MaxPowerPointVoltageVEditField.Tag = 'Vmpp';
            app.FillFactorEditField_2Label = uilabel(app.CurveGrid);
            app.FillFactorEditField_2Label.Text = 'Fill Factor';
            app.FillFactorEditField = uieditfield(app.CurveGrid, 'numeric');
            app.FillFactorEditField.Tag = 'FF';
            app.EfficiencyEditField_2Label = uilabel(app.CurveGrid);
            app.EfficiencyEditField_2Label.Text = 'Efficiency (%)';
            app.EfficiencyEditField = uieditfield(app.CurveGrid, 'numeric');
            app.EfficiencyEditField.Tag = 'Efficiency';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.HistoryLabel = uilabel(app.GridLayout4);
            app.HistoryLabel.Text = 'History';
            app.ListBox = uilistbox(app.GridLayout4);
            app.ListBox.Items = {''};
            app.ListBox.DoubleClickedFcn = createCallbackFcn(app, @ListBoxDoubleClicked, true);
            app.ListBox.Value = {};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout2);
            app.EditDropDownLabel = uilabel(app.Panel);
            app.EditDropDownLabel.Text = 'Edit';
            app.EditDropDown = uidropdown(app.Panel);
            app.EditDropDown.Items = {'Geometric Properties', 'Hole Mobility', 'Density of States', 'Energy Level Alignment', 'Work Function', 'Relative Permittivity', 'Charge Generation', 'Recombination Coefficient', 'Charge Carrier Lifetime', 'Recombination Velocity'};
            app.EditDropDown.ValueChangedFcn = createCallbackFcn(app, @EditDropDownValueChanged, true);
            app.EditDropDown.Value = 'Geometric Properties';
            app.SolarCellParametersLabel = uilabel(app.GridLayout2);
            app.SolarCellParametersLabel.Text = 'Solar Cell Parameters';
            app.ParameterPanel = uipanel(app.GridLayout2);
            app.ParameterPanel.Title = 'Geometric Properties';
            app.ParameterGrid = uigridlayout(app.ParameterPanel);
            app.HLayerThicknessnmEditFieldLabel = uilabel(app.ParameterGrid);
            app.HLayerThicknessnmEditFieldLabel.Text = 'H Layer Thickness (nm)';
            app.HLayerThicknessnmEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.HLayerThicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @HLayerThicknessnmEditFieldValueChanged, true);
            app.HLayerThicknessnmEditField.Tag = 'LH';
            app.PLayerThicknessnmEditFieldLabel = uilabel(app.ParameterGrid);
            app.PLayerThicknessnmEditFieldLabel.Text = 'P Layer Thickness (nm)';
            app.PLayerThicknessnmEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.PLayerThicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @PLayerThicknessnmEditFieldValueChanged, true);
            app.PLayerThicknessnmEditField.Tag = 'LP';
            app.ELayerThicknessnmEditFieldLabel = uilabel(app.ParameterGrid);
            app.ELayerThicknessnmEditFieldLabel.Text = 'E Layer Thickness (nm)';
            app.ELayerThicknessnmEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ELayerThicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @ELayerThicknessnmEditFieldValueChanged, true);
            app.ELayerThicknessnmEditField.Tag = 'LE';
            app.HoleMobilityinHLabel = uilabel(app.ParameterGrid);
            app.HoleMobilityinHLabel.Interpreter = 'html';
            app.HoleMobilityinHLabel.Text = 'Hole Mobility in H (m<sup>2</sup> V<sup>-1</sup> s<sup>-1</sup>)';
            app.HoleMobilityinHmsup2supVsup1supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.HoleMobilityinHmsup2supVsup1supssup1supEditField.Tag = 'muHh';
            app.HoleMobilityinPmsup2supVsup1supssup1supEditFieldLabel = uilabel(app.ParameterGrid);
            app.HoleMobilityinPmsup2supVsup1supssup1supEditFieldLabel.Interpreter = 'html';
            app.HoleMobilityinPmsup2supVsup1supssup1supEditFieldLabel.Text = 'Hole Mobility in P (m<sup>2</sup> V<sup>-1</sup> s<sup>-1</sup>)';
            app.HoleMobilityinPmsup2supVsup1supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.HoleMobilityinPmsup2supVsup1supssup1supEditField.Tag = 'muPh';
            app.ElectronMobilityinPmsup2supVsup1supssup1supEditFieldLabel = uilabel(app.ParameterGrid);
            app.ElectronMobilityinPmsup2supVsup1supssup1supEditFieldLabel.Interpreter = 'html';
            app.ElectronMobilityinPmsup2supVsup1supssup1supEditFieldLabel.Text = 'Electron Mobility in P (m<sup>2</sup> V<sup>-1</sup> s<sup>-1</sup>)';
            app.ElectronMobilityinPmsup2supVsup1supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ElectronMobilityinPmsup2supVsup1supssup1supEditField.Tag = 'muPe';
            app.ElectronMobilityinELabel = uilabel(app.ParameterGrid);
            app.ElectronMobilityinELabel.Interpreter = 'html';
            app.ElectronMobilityinELabel.Text = 'Electron Mobility in E (m<sup>2</sup> V<sup>-1</sup> s<sup>-1</sup>)';
            app.ElectronMobilityinEmsup2supVsup1supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ElectronMobilityinEmsup2supVsup1supssup1supEditField.Tag = 'muEe';
            app.ValanceBandDensityinHmsup3supEditFieldLabel = uilabel(app.ParameterGrid);
            app.ValanceBandDensityinHmsup3supEditFieldLabel.Interpreter = 'html';
            app.ValanceBandDensityinHmsup3supEditFieldLabel.Text = 'Valance Band Density in H (m<sup>-3</sup> )';
            app.ValanceBandDensityinHmsup3supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ValanceBandDensityinHmsup3supEditField.Tag = 'NvH';
            app.ConductionBandDensityinHmsup3supEditFieldLabel = uilabel(app.ParameterGrid);
            app.ConductionBandDensityinHmsup3supEditFieldLabel.Interpreter = 'html';
            app.ConductionBandDensityinHmsup3supEditFieldLabel.Text = 'Conduction Band Density in H (m<sup>-3</sup> )';
            app.ConductionBandDensityinHmsup3supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ConductionBandDensityinHmsup3supEditField.Tag = 'NcH';
            app.ValanceBandDensityinPLabel = uilabel(app.ParameterGrid);
            app.ValanceBandDensityinPLabel.Interpreter = 'html';
            app.ValanceBandDensityinPLabel.Text = 'Valance Band Density in P (m<sup>-3</sup> )';
            app.ValanceBandDensityinPmsup3supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ValanceBandDensityinPmsup3supEditField.Tag = 'NvP';
            app.ConductionBandDensityinPmsup3supEditFieldLabel = uilabel(app.ParameterGrid);
            app.ConductionBandDensityinPmsup3supEditFieldLabel.Interpreter = 'html';
            app.ConductionBandDensityinPmsup3supEditFieldLabel.Text = 'Conduction Band Density in P (m<sup>-3</sup> )';
            app.ConductionBandDensityinPmsup3supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ConductionBandDensityinPmsup3supEditField.Tag = 'NcP';
            app.ConductionBandDensityinEmsup3supEditFieldLabel = uilabel(app.ParameterGrid);
            app.ConductionBandDensityinEmsup3supEditFieldLabel.Interpreter = 'html';
            app.ConductionBandDensityinEmsup3supEditFieldLabel.Text = 'Conduction Band Density in E (m<sup>-3</sup> )';
            app.ConductionBandDensityinEmsup3supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ConductionBandDensityinEmsup3supEditField.Tag = 'NcE';
            app.ValanceBandDensityinEmsup3supEditFieldLabel = uilabel(app.ParameterGrid);
            app.ValanceBandDensityinEmsup3supEditFieldLabel.Interpreter = 'html';
            app.ValanceBandDensityinEmsup3supEditFieldLabel.Text = 'Valance Band Density in E (m<sup>-3</sup> )';
            app.ValanceBandDensityinEmsup3supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ValanceBandDensityinEmsup3supEditField.Tag = 'NvE';
            app.HoleIonizationPotentialinHeVEditFieldLabel = uilabel(app.ParameterGrid);
            app.HoleIonizationPotentialinHeVEditFieldLabel.Text = 'Hole Ionization Potential in H (eV)';
            app.HoleIonizationPotentialinHeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.HoleIonizationPotentialinHeVEditField.Tag = 'chiHh';
            app.HoleIonizationPotentialinPeVEditFieldLabel = uilabel(app.ParameterGrid);
            app.HoleIonizationPotentialinPeVEditFieldLabel.Text = 'Hole Ionization Potential in P (eV)';
            app.HoleIonizationPotentialinPeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.HoleIonizationPotentialinPeVEditField.Tag = 'chiPh';
            app.HoleIonizationPotentialinEeVEditFieldLabel = uilabel(app.ParameterGrid);
            app.HoleIonizationPotentialinEeVEditFieldLabel.Text = 'Hole Ionization Potential in E (eV)';
            app.HoleIonizationPotentialinEeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.HoleIonizationPotentialinEeVEditField.Tag = 'chiEh';
            app.ElectronAffinityinHeVEditFieldLabel = uilabel(app.ParameterGrid);
            app.ElectronAffinityinHeVEditFieldLabel.Text = 'Electron Affinity in H (eV)';
            app.ElectronAffinityinHeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ElectronAffinityinHeVEditField.Tag = 'chiHe';
            app.ElectronAffinityinPeVEditFieldLabel = uilabel(app.ParameterGrid);
            app.ElectronAffinityinPeVEditFieldLabel.Text = 'Electron Affinity in P (eV)';
            app.ElectronAffinityinPeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ElectronAffinityinPeVEditField.Tag = 'chiPe';
            app.ElectronAffinityinEeVEditFieldLabel = uilabel(app.ParameterGrid);
            app.ElectronAffinityinEeVEditFieldLabel.Text = 'Electron Affinity in E (eV)';
            app.ElectronAffinityinEeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ElectronAffinityinEeVEditField.Tag = 'chiEe';
            app.WorkFunctionofBLabel = uilabel(app.ParameterGrid);
            app.WorkFunctionofBLabel.Text = 'Work Function of B (eV)';
            app.WorkFunctionofBeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.WorkFunctionofBeVEditField.Tag = 'Wlm';
            app.WorkFunctionofFeVEditFieldLabel = uilabel(app.ParameterGrid);
            app.WorkFunctionofFeVEditFieldLabel.Text = 'Work Function of F (eV)';
            app.WorkFunctionofFeVEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.WorkFunctionofFeVEditField.Tag = 'Whm';
            app.RelativePermittivityinHEditFieldLabel = uilabel(app.ParameterGrid);
            app.RelativePermittivityinHEditFieldLabel.Text = 'Relative Permittivity in H';
            app.RelativePermittivityinHEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.RelativePermittivityinHEditField.Tag = 'epsH';
            app.RelativePermittivityinPEditFieldLabel = uilabel(app.ParameterGrid);
            app.RelativePermittivityinPEditFieldLabel.Text = 'Relative Permittivity in P';
            app.RelativePermittivityinPEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.RelativePermittivityinPEditField.Tag = 'epsP';
            app.RelativePermittivityinEEditFieldLabel = uilabel(app.ParameterGrid);
            app.RelativePermittivityinEEditFieldLabel.Text = 'Relative Permittivity in E';
            app.RelativePermittivityinEEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.RelativePermittivityinEEditField.Tag = 'epsE';
            app.ChargeGenerationRateinPLabel = uilabel(app.ParameterGrid);
            app.ChargeGenerationRateinPLabel.Interpreter = 'html';
            app.ChargeGenerationRateinPLabel.Text = 'Charge Generation Rate in P (m<sup>-3</sup> s<sup>-1</sup>)';
            app.ChargeGenerationRateinPmsup3supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ChargeGenerationRateinPmsup3supssup1supEditField.Tag = 'Gavg';
            app.AugerRecombinationinPmsup6supssup1supEditFieldLabel = uilabel(app.ParameterGrid);
            app.AugerRecombinationinPmsup6supssup1supEditFieldLabel.Interpreter = 'html';
            app.AugerRecombinationinPmsup6supssup1supEditFieldLabel.Text = 'Auger Recombination in P (m<sup>6</sup> s<sup>-1</sup>)';
            app.AugerRecombinationinPmsup6supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.AugerRecombinationinPmsup6supssup1supEditField.Tag = 'Aug';
            app.RadiativeRecombinationinPmsup3supssup1supEditFieldLabel = uilabel(app.ParameterGrid);
            app.RadiativeRecombinationinPmsup3supssup1supEditFieldLabel.Interpreter = 'html';
            app.RadiativeRecombinationinPmsup3supssup1supEditFieldLabel.Text = 'Radiative Recombination in P (m<sup>3</sup> s<sup>-1</sup>)';
            app.RadiativeRecombinationinPmsup3supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.RadiativeRecombinationinPmsup3supssup1supEditField.Tag = 'Brad';
            app.ElectronLifetimeinPsEditFieldLabel = uilabel(app.ParameterGrid);
            app.ElectronLifetimeinPsEditFieldLabel.Text = 'Electron Lifetime in P (s)';
            app.ElectronLifetimeinPsEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.ElectronLifetimeinPsEditField.Tag = 'Taue';
            app.HoleLifetimeinPsEditFieldLabel = uilabel(app.ParameterGrid);
            app.HoleLifetimeinPsEditFieldLabel.Text = 'Hole Lifetime in P (s)';
            app.HoleLifetimeinPsEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.HoleLifetimeinPsEditField.Tag = 'Tauh';
            app.RecombinationVelocityatIImsup4supssup1supEditFieldLabel = uilabel(app.ParameterGrid);
            app.RecombinationVelocityatIImsup4supssup1supEditFieldLabel.Interpreter = 'html';
            app.RecombinationVelocityatIImsup4supssup1supEditFieldLabel.Text = 'Recombination Velocity at II (m<sup>4</sup> s<sup>-1</sup>)';
            app.RecombinationVelocityatIImsup4supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.RecombinationVelocityatIImsup4supssup1supEditField.Tag = 'vII';
            app.RecombinationVelocityatIIImsup4supssup1supEditFieldLabel = uilabel(app.ParameterGrid);
            app.RecombinationVelocityatIIImsup4supssup1supEditFieldLabel.Interpreter = 'html';
            app.RecombinationVelocityatIIImsup4supssup1supEditFieldLabel.Text = 'Recombination Velocity at III (m<sup>4</sup> s<sup>-1</sup>)';
            app.RecombinationVelocityatIIImsup4supssup1supEditField = uieditfield(app.ParameterGrid, 'numeric');
            app.RecombinationVelocityatIIImsup4supssup1supEditField.Tag = 'vIII';
            app.PredictButton = uibutton(app.GridLayout2, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.LoadFileButton = uibutton(app.GridLayout2, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.ClearButton = uibutton(app.GridLayout2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Perovskite_Solar_Cell_Performance_Prediction_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end